classdef Adaline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PrecisionEditField             matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel        matlab.ui.control.Label
        ErrorFinalEditField            matlab.ui.control.NumericEditField
        ErrorFinalEditFieldLabel       matlab.ui.control.Label
        RESULTADOEditField             matlab.ui.control.NumericEditField
        RESULTADOEditFieldLabel        matlab.ui.control.Label
        ComprobarButton                matlab.ui.control.Button
        entrada4Switch                 matlab.ui.control.Switch
        entrada4SwitchLabel            matlab.ui.control.Label
        entrada3Switch                 matlab.ui.control.Switch
        entrada3SwitchLabel            matlab.ui.control.Label
        entrada2Switch                 matlab.ui.control.Switch
        entrada2SwitchLabel            matlab.ui.control.Label
        entrada1Switch                 matlab.ui.control.Switch
        entrada1SwitchLabel            matlab.ui.control.Label
        X4EditField                    matlab.ui.control.NumericEditField
        X4EditFieldLabel               matlab.ui.control.Label
        W4EditField                    matlab.ui.control.NumericEditField
        W4EditFieldLabel               matlab.ui.control.Label
        SELECCIONAUNCASODropDown       matlab.ui.control.DropDown
        SELECCIONAUNCASODropDownLabel  matlab.ui.control.Label
        X3EditField                    matlab.ui.control.NumericEditField
        X3EditFieldLabel               matlab.ui.control.Label
        X2EditField                    matlab.ui.control.NumericEditField
        X2EditFieldLabel               matlab.ui.control.Label
        X1EditField                    matlab.ui.control.NumericEditField
        X1EditFieldLabel               matlab.ui.control.Label
        TasadeaprendizajeEditField     matlab.ui.control.NumericEditField
        TasadeaprendizajeLabel         matlab.ui.control.Label
        NumeroEpocasEditField          matlab.ui.control.NumericEditField
        NumerodeepocasrealizadasEditFieldLabel  matlab.ui.control.Label
        ReiniciarButton                matlab.ui.control.Button
        W3EditField                    matlab.ui.control.NumericEditField
        W3EditFieldLabel               matlab.ui.control.Label
        W1EditField                    matlab.ui.control.NumericEditField
        W1EditFieldLabel               matlab.ui.control.Label
        UmbralEditField                matlab.ui.control.NumericEditField
        UmbralEditFieldLabel           matlab.ui.control.Label
        W2EditField                    matlab.ui.control.NumericEditField
        W2EditFieldLabel               matlab.ui.control.Label
        GenerarpesosaleatoriosButton   matlab.ui.control.Button
        CargarpesosButton              matlab.ui.control.Button
        error_deseado                  matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        ENTRENARButton                 matlab.ui.control.Button
        CargarentradasysalidasesperadasButton  matlab.ui.control.Button
        Image5                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        error                          matlab.ui.control.UIAxes
    end

    
    % === Propiedades personalizadas para el modelo Adaline ===
    properties (Access = public)
        W        % Vector de pesos [W1 W2 W3 Umbral]
        Entradas % Matriz de entradas
        SalidasDeseadas      % Vector de salidas deseadas cargadas
        Pesos                % Vector de pesos actuales
        Umbral               % Umbral del modelo
        PesosFinales
        BiasFinal

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ocultar todas las entradas al inicio
    app.entrada1Switch.Visible = 'off';
    app.entrada1SwitchLabel.Visible = 'off';
    app.entrada2Switch.Visible = 'off';
    app.entrada2SwitchLabel.Visible = 'off';
    app.entrada3Switch.Visible = 'off';
    app.entrada3SwitchLabel.Visible = 'off';
    app.entrada4Switch.Visible = 'off';
    app.entrada4SwitchLabel.Visible = 'off';

    % Inicializar pesos y umbral vacíos
    app.W1EditField.Value = [];
    app.W2EditField.Value = [];
    app.W3EditField.Value = [];
    app.W4EditField.Value = [];
    app.UmbralEditField.Value = [];
    app.error_deseado.Value = [];

    % Resultado inicial en 0
    app.RESULTADOEditField.Value = [];
    

    % Dejar el DropDown en "Seleccionar"
    app.SELECCIONAUNCASODropDown.Value = 'SELECCIONAR';
        end

        % Button pushed function: CargarpesosButton
        function CargarpesosButtonPushed(app, event)

                caso = app.SELECCIONAUNCASODropDown.Value;

                    % Inicializar todo vacío
                    app.W1EditField.Value = [];
                    app.W2EditField.Value = [];
                    app.W3EditField.Value = [];
                    app.W4EditField.Value = [];
                    app.UmbralEditField.Value = [];
                
                    % Leer pesos de un solo archivo (pesos.txt en la misma carpeta)
                    pesos = load('pesos.txt');  % debe contener 5 valores en una fila
                
                    switch caso
                        case 'Caso 1'   % 2 pesos + umbral
                            app.W1EditField.Value = pesos(1);
                            app.W2EditField.Value = pesos(2);
                            app.UmbralEditField.Value = pesos(5);
                
                        case 'Caso 2'   % 3 pesos + umbral
                            app.W1EditField.Value = pesos(1);
                            app.W2EditField.Value = pesos(2);
                            app.W3EditField.Value = pesos(3);
                            app.UmbralEditField.Value = pesos(5);
                
                        case 'Caso 3'   % 4 pesos + umbral
                            app.W1EditField.Value = pesos(1);
                            app.W2EditField.Value = pesos(2);
                            app.W3EditField.Value = pesos(3);
                            app.W4EditField.Value = pesos(4);
                            app.UmbralEditField.Value = pesos(5);
                                                                             
                       
                end

        end

        % Button pushed function: GenerarpesosaleatoriosButton
        function GenerarpesosaleatoriosButtonPushed(app, event)
        caso = app.SELECCIONAUNCASODropDown.Value;
        
        % Definir rango de inicialización
        rangoMin = -1;
        rangoMax = 1;
        
        % Inicializar todo vacío
        app.W1EditField.Value = [];
        app.W2EditField.Value = [];
        app.W3EditField.Value = [];
        app.W4EditField.Value = [];
        app.UmbralEditField.Value = [];
        
        switch caso
            case 'Caso 1'   % 2 pesos + umbral
                app.W1EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.W2EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.UmbralEditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
        
            case 'Caso 2'   % 3 pesos + umbral
                app.W1EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.W2EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.W3EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.UmbralEditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
        
            case 'Caso 3'   % 4 pesos + umbral
                app.W1EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.W2EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.W3EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.W4EditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
                app.UmbralEditField.Value = rangoMin + (rangoMax-rangoMin)*rand();
        end


        end

        % Button pushed function: CargarentradasysalidasesperadasButton
        function CargarentradasysalidasesperadasButtonPushed(app, event)


                caso = app.SELECCIONAUNCASODropDown.Value;
            
                % Inicializar vacíos
                app.Entradas = [];
                app.SalidasDeseadas = [];
            
                switch caso
                    case 'Caso 1'
                        app.Entradas = load('entradas_caso1.txt');
                        app.SalidasDeseadas = load('salidas_caso1.txt');
            
                    case 'Caso 2'
                        app.Entradas = load('entradas_caso2.txt');
                        app.SalidasDeseadas = load('salidas_caso2.txt');
            
                    case 'Caso 3'
                        app.Entradas = load('entradas_caso3.txt');
                        app.SalidasDeseadas = load('salidas_caso3.txt');
                end
            
                % Mostrar confirmación
                uialert(app.UIFigure, ...
                    sprintf('Entradas y salidas cargadas para %s', caso), ...
                    'Carga completada');


        end

        % Button pushed function: ENTRENARButton
        function ENTRENARButtonPushed(app, event)

  % Verificar que haya datos cargados
    if isempty(app.Entradas) || isempty(app.SalidasDeseadas)
        uialert(app.UIFigure, 'Primero debes cargar entradas y salidas esperadas', 'Error');
        return;
    end
    
    % Verificar que haya pesos inicializados
    caso = app.SELECCIONAUNCASODropDown.Value;
    switch caso
        case 'Caso 1'
            if isempty(app.W1EditField.Value) || isempty(app.W2EditField.Value) || isempty(app.UmbralEditField.Value)
                uialert(app.UIFigure, 'Primero debes cargar o generar pesos para el Caso 1', 'Error');
                return;
            end
        case 'Caso 2'
            if isempty(app.W1EditField.Value) || isempty(app.W2EditField.Value) || isempty(app.W3EditField.Value) || isempty(app.UmbralEditField.Value)
                uialert(app.UIFigure, 'Primero debes cargar o generar pesos para el Caso 2', 'Error');
                return;
            end
        case 'Caso 3'
            if isempty(app.W1EditField.Value) || isempty(app.W2EditField.Value) || isempty(app.W3EditField.Value) || isempty(app.W4EditField.Value) || isempty(app.UmbralEditField.Value)
                uialert(app.UIFigure, 'Primero debes cargar o generar pesos para el Caso 3', 'Error');
                return;
            end
    end
    
    % Leer parámetros desde los EditFields de la app
    alfa = app.TasadeaprendizajeEditField.Value;  % tasa de aprendizaje

% Validar que el usuario la ingresó
if isempty(alfa) || alfa <= 0
    uialert(app.UIFigure, ...
        'Debes ingresar una tasa de aprendizaje (α) válida antes de entrenar.', ...
        'Error');
    return; % no entrenar si no es válido
end
    
    epochs = app.NumeroEpocasEditField.Value;     % número de épocas a entrenar
    if isempty(epochs) || epochs <= 0
        epochs = 1000; % valor por defecto
        app.NumeroEpocasEditField.Value = epochs;
    end
    
    % Obtener dimensiones
    [nSamples, nFeatures] = size(app.Entradas);
    
    % Inicialización de pesos desde la interfaz
    switch caso
        case 'Caso 1'
            pesos = [app.W1EditField.Value; app.W2EditField.Value];
        case 'Caso 2'
            pesos = [app.W1EditField.Value; app.W2EditField.Value; app.W3EditField.Value];
        case 'Caso 3'
            pesos = [app.W1EditField.Value; app.W2EditField.Value; app.W3EditField.Value; app.W4EditField.Value];
    end
    bias = app.UmbralEditField.Value;
    
    % Vector para guardar el error por época
    error_epoca = zeros(epochs,1);
    
    % Leer error deseado desde archivo .txt
    try
        % Leer error deseado desde la app
        error_deseado = app.error_deseado.Value;
        
        % Validar que sea un valor válido
        if isempty(error_deseado) || error_deseado <= 0
            uialert(app.UIFigure, ...
                'Debes ingresar un valor válido para el error deseado (mayor que 0).', ...
                'Error');
            return; % detener entrenamiento
        end

    catch
        error_deseado = 0.01; % valor por defecto si no se puede leer el archivo
        fprintf('No se pudo leer error_deseado.txt, usando valor por defecto: %.4f\n', error_deseado);
    end
    
    % Actualizar el EditField para mostrar el error deseado leído
    app.error_deseado.Value = error_deseado;
    
    % Inicializar gráfica
    cla(app.error);
    
    % Configurar gráfica
    xlabel(app.error, 'Época');
    ylabel(app.error, 'Error cuadrático medio');
    title(app.error, 'Error vs Epocas acumuladas');
    grid(app.error, 'on');
    
    % Configurar límites iniciales
    app.error.XLim = [1 max(epochs, 10)];
    app.error.YLim = [0 1]; % Se ajustará dinámicamente
    
    % Entrenamiento con regla delta (LMS)
    for epoch = 1:epochs
        suma_error = 0;
        for i = 1:nSamples
            x = app.Entradas(i,:)';
            y = app.SalidasDeseadas(i);
            
            % Salida lineal de Adaline (sin función de activación)
            salida = pesos' * x + bias;
            
            % Error lineal
            error = y - salida;
            
            % Actualización de pesos (regla LMS/Widrow-Hoff)
            pesos = pesos + alfa * error * x;
            bias = bias + alfa * error;
            
            % Acumular error cuadrático
            suma_error = suma_error + error^2;
        end
        
        % Error cuadrático medio por época
        error_epoca(epoch) = suma_error / nSamples;
        
        % Actualizar gráfica cada época para ver el progreso en tiempo real
        if mod(epoch, 1) == 0 || epoch == epochs || epoch == 1
            % Dibujar la gráfica directamente
            plot(app.error, 1:epoch, error_epoca(1:epoch), 'b-o', 'LineWidth', 2, 'MarkerSize', 4);
            
            % Configurar gráfica cada vez
            xlabel(app.error, 'Época');
            ylabel(app.error, 'Error cuadrático medio');
            title(app.error, 'Error vs Epocas acumuladas');
            grid(app.error, 'on');
            
            % Ajustar límites dinámicamente
            app.error.XLim = [1 max(epoch, 10)];
            max_error = max(error_epoca(1:epoch));
            app.error.YLim = [0 max_error * 1.1];
            
            % Forzar actualización de la gráfica
            drawnow;
            
            % Mostrar progreso en consola cada 10 épocas
            if mod(epoch, 10) == 0
                fprintf('Época %d/%d - Error: %.6f\n', epoch, epochs, error_epoca(epoch));
            end
        end
        
        % Criterio de parada por error deseado
        if error_epoca(epoch) < error_deseado
            fprintf('Convergencia alcanzada en época %d\n', epoch);
            epochs = epoch; % ajustar para la gráfica
            error_epoca = error_epoca(1:epoch);
            
            % Gráfica final
            plot(app.error, 1:epoch, error_epoca, 'b-o', 'LineWidth', 2, 'MarkerSize', 4);
            xlabel(app.error, 'Época');
            ylabel(app.error, 'Error cuadrático medio');
            title(app.error, 'Error vs Epocas acumuladas');
            grid(app.error, 'on');
            app.error.XLim = [1 max(epoch, 2)];
            max_error = max(error_epoca);
            app.error.YLim = [0 max_error * 1.1];
            drawnow;
            break;
        end
    end
    
    % Gráfica final (por si no se actualizó en la última época)
    plot(app.error, 1:epochs, error_epoca, 'b-o', 'LineWidth', 2, 'MarkerSize', 4);
    xlabel(app.error, 'Época');
    ylabel(app.error, 'Error cuadrático medio');
    title(app.error, 'Error vs Epocas acumuladas');
    grid(app.error, 'on');
    app.error.XLim = [1 max(epochs, 2)];
    if ~isempty(error_epoca)
        max_error = max(error_epoca);
        app.error.YLim = [0 max_error * 1.1];
    end
    drawnow;
    
    % Guardar pesos finales
    app.PesosFinales = pesos;
    app.BiasFinal = bias;
    
    % Actualizar pesos en la interfaz
    switch caso
        case 'Caso 1'
            app.W1EditField.Value = pesos(1);
            app.W2EditField.Value = pesos(2);
        case 'Caso 2'
            app.W1EditField.Value = pesos(1);
            app.W2EditField.Value = pesos(2);
            app.W3EditField.Value = pesos(3);
        case 'Caso 3'
            app.W1EditField.Value = pesos(1);
            app.W2EditField.Value = pesos(2);
            app.W3EditField.Value = pesos(3);
            app.W4EditField.Value = pesos(4);
    end
    app.UmbralEditField.Value = bias;
    
    % Actualizar número de épocas realizadas
    app.NumeroEpocasEditField.Value = epochs;
    
    % Imprimir resultados
    fprintf('\n=== ENTRENAMIENTO COMPLETADO ===\n');
    fprintf('Épocas realizadas: %d\n', epochs);
    fprintf('Error final: %.6f\n', error_epoca(end));
    fprintf('Pesos finales: [');
    fprintf('%.4f ', pesos);
    fprintf(']\n');
    fprintf('Bias final: %.4f\n', bias);
    
    % === PRUEBA AUTOMÁTICA DE TODAS LAS COMBINACIONES ===
    fprintf('\n=== PRUEBA DE TODAS LAS COMBINACIONES ===\n');
    fprintf('Entrada\t\t\tSalida Real\tSalida Esperada\tError\n');
    fprintf('-------\t\t\t-----------\t---------------\t-----\n');
    
    [nSamples, ~] = size(app.Entradas);
    
    for i = 1:nSamples
        x = app.Entradas(i,:)';
        salida_real = app.PesosFinales' * x + app.BiasFinal;
        salida_esperada = app.SalidasDeseadas(i);
        error_individual = abs(salida_esperada - salida_real);
        
        fprintf('[');
        fprintf('%.0f ', x);
        fprintf(']\t\t%.4f\t\t%.4f\t\t\t%.4f\n', salida_real, salida_esperada, error_individual);
    end
    
    % Calcular error total final
    salidas_reales = app.PesosFinales' * app.Entradas' + app.BiasFinal;
    error_total_final = mean((app.SalidasDeseadas' - salidas_reales).^2);
    fprintf('\nError cuadrático medio total: %.6f\n', error_total_final);
    
    % Calcular porcentaje de acierto para clasificación binaria
    clasificaciones_reales = salidas_reales >= 0;
    clasificaciones_esperadas = app.SalidasDeseadas' >= 0.5;
    aciertos = sum(clasificaciones_reales == clasificaciones_esperadas);
    porcentaje_acierto = (aciertos / nSamples) * 100;
    fprintf('Precisión de clasificación binaria: %.2f%% (%d/%d)\n', porcentaje_acierto, aciertos, nSamples);
    
    % Guardar información en archivo .txt
    try
        % Crear archivo con los resultados finales
        fid = fopen('resultados_entrenamiento.txt', 'w');
        fprintf(fid, 'ErrorFinal: %.6f\n', error_epoca(end));
        fprintf(fid, 'Precision: %.2f\n', porcentaje_acierto);
        fprintf(fid, 'NumeroEpocas: %d\n', epochs);
        fclose(fid);
        fprintf('Resultados guardados en resultados_entrenamiento.txt\n');
    catch
        fprintf('Error al guardar resultados en archivo\n');
    end
    
    % Actualizar campos en la interfaz
    app.NumeroEpocasEditField.Value = epochs;
    app.ErrorFinalEditField.Value = error_epoca(end);
    app.PrecisionEditField.Value = porcentaje_acierto;
    
    fprintf('Valores actualizados en la interfaz:\n');
    fprintf('- Número de épocas: %d\n', epochs);
    fprintf('- Error final: %.6f\n', error_epoca(end));
    fprintf('- Precisión: %.2f%%\n', porcentaje_acierto);


        end

        % Value changed function: SELECCIONAUNCASODropDown
        function SELECCIONAUNCASODropDownValueChanged(app, event)
           caso = app.SELECCIONAUNCASODropDown.Value;
    
    % Limpiar resultado anterior
    app.RESULTADOEditField.Value = 0;
    
    % Limpiar campos X
    app.X1EditField.Value = [];
    app.X2EditField.Value = [];
    app.X3EditField.Value = [];
    app.X4EditField.Value = [];
    
    % Resetear switches a 0
    app.entrada1Switch.Value = '0';
    app.entrada2Switch.Value = '0';
    app.entrada3Switch.Value = '0';
    app.entrada4Switch.Value = '0';

    % Ocultar todas las entradas inicialmente
    app.entrada1Switch.Visible = 'off';
    app.entrada1SwitchLabel.Visible = 'off';
    app.entrada2Switch.Visible = 'off';
    app.entrada2SwitchLabel.Visible = 'off';
    app.entrada3Switch.Visible = 'off';
    app.entrada3SwitchLabel.Visible = 'off';
    app.entrada4Switch.Visible = 'off';
    app.entrada4SwitchLabel.Visible = 'off';
    
    switch caso

        case 'Seleccionar'
            % No mostrar nada, solo queda limpio
            uialert(app.UIFigure,'Seleccione un caso válido.','Aviso');
            
        case 'Caso 1'   % Solo 2 entradas
            % Mostrar entradas 1 y 2
            app.entrada1Switch.Visible = 'on';
            app.entrada1SwitchLabel.Visible = 'on';
            app.entrada2Switch.Visible = 'on';
            app.entrada2SwitchLabel.Visible = 'on';
            
            % Ocultar entradas 3 y 4
            app.entrada3Switch.Visible = 'off';
            app.entrada3SwitchLabel.Visible = 'off';
            app.entrada4Switch.Visible = 'off';
            app.entrada4SwitchLabel.Visible = 'off';
            
        case 'Caso 2'   % 3 entradas
            % Mostrar entradas 1, 2 y 3
            app.entrada1Switch.Visible = 'on';
            app.entrada1SwitchLabel.Visible = 'on';
            app.entrada2Switch.Visible = 'on';
            app.entrada2SwitchLabel.Visible = 'on';
            app.entrada3Switch.Visible = 'on';
            app.entrada3SwitchLabel.Visible = 'on';
            
            % Ocultar entrada 4
            app.entrada4Switch.Visible = 'off';
            app.entrada4SwitchLabel.Visible = 'off';
            
        case 'Caso 3'   % 4 entradas
            % Mostrar todas las entradas
            app.entrada1Switch.Visible = 'on';
            app.entrada1SwitchLabel.Visible = 'on';
            app.entrada2Switch.Visible = 'on';
            app.entrada2SwitchLabel.Visible = 'on';
            app.entrada3Switch.Visible = 'on';
            app.entrada3SwitchLabel.Visible = 'on';
            app.entrada4Switch.Visible = 'on';
            app.entrada4SwitchLabel.Visible = 'on';
    end
    
    % Mostrar mensaje informativo
    fprintf('\n=== CASO CAMBIADO ===\n');
    fprintf('Seleccionado: %s\n', caso);
    switch caso
        case 'Caso 1'
            fprintf('Entradas activas: entrada1, entrada2\n');
        case 'Caso 2'
            fprintf('Entradas activas: entrada1, entrada2, entrada3\n');
        case 'Caso 3'
            fprintf('Entradas activas: entrada1, entrada2, entrada3, entrada4\n');
    end
    fprintf('Los switches han sido reseteados a 0\n');
        end

        % Button pushed function: ComprobarButton
        function ComprobarButtonPushed(app, event)
            % Verificar que la red esté entrenada
    if isempty(app.PesosFinales) || isempty(app.BiasFinal)
        uialert(app.UIFigure, 'Primero debes entrenar la red neuronal', 'Error');
        app.RESULTADOEditField.Value = 0; % limpiar resultado
        return;
    end
    
    % Obtener caso actual
    caso = app.SELECCIONAUNCASODropDown.Value;
    
    % Leer valores de entrada desde los switches según el caso
    entradas = [];
    switch caso
    case 'Caso 1'   % Solo 2 entradas
        entradas = [str2double(app.entrada1Switch.Value); 
                   str2double(app.entrada2Switch.Value)];
        % Limpiar campos no usados
        app.X3EditField.Value = [];
        app.X4EditField.Value = [];
        
    case 'Caso 2'   % 3 entradas
        entradas = [str2double(app.entrada1Switch.Value); 
                   str2double(app.entrada2Switch.Value);
                   str2double(app.entrada3Switch.Value)];
        % Limpiar campo no usado
        app.X4EditField.Value = [];
        
    case 'Caso 3'   % 4 entradas
        entradas = [str2double(app.entrada1Switch.Value); 
                   str2double(app.entrada2Switch.Value);
                   str2double(app.entrada3Switch.Value);
                   str2double(app.entrada4Switch.Value)];
end

    
    % Verificar que tenemos las entradas correctas
    if length(entradas) ~= length(app.PesosFinales)
        uialert(app.UIFigure, sprintf('Error: %s requiere %d entradas, pero se encontraron %d pesos', caso, length(entradas), length(app.PesosFinales)), 'Error de dimensión');
        app.RESULTADOEditField.Value = 0;
        return;
    end
    
    % Calcular salida usando los pesos entrenados
    salida = app.PesosFinales' * entradas + app.BiasFinal;
    
    % Actualizar campos de entrada X para mostrar valores usados
    switch caso
        case 'Caso 1'
            app.X1EditField.Value = entradas(1);
            app.X2EditField.Value = entradas(2);
        case 'Caso 2'
            app.X1EditField.Value = entradas(1);
            app.X2EditField.Value = entradas(2);
            app.X3EditField.Value = entradas(3);
        case 'Caso 3'
            app.X1EditField.Value = entradas(1);
            app.X2EditField.Value = entradas(2);
            app.X3EditField.Value = entradas(3);
            app.X4EditField.Value = entradas(4);
    end
    
    % Mostrar resultado (redondeado a 6 decimales)
    app.RESULTADOEditField.Value = round(salida, 6);
    
    % Mostrar información en la consola
    fprintf('\n=== COMPROBACIÓN %s ===\n', caso);
    fprintf('Entradas activas: [');
    fprintf('%.0f ', entradas);
    fprintf(']\n');
    fprintf('Pesos: [');
    fprintf('%.6f ', app.PesosFinales);
    fprintf(']\n');
    fprintf('Bias: %.6f\n', app.BiasFinal);
    fprintf('Salida calculada: %.6f\n', salida);
    
    % Mostrar cálculo paso a paso
    fprintf('Cálculo: ');
    for i = 1:length(entradas)
        if i == 1
            fprintf('%.6f*%.0f', app.PesosFinales(i), entradas(i));
        else
            fprintf(' + %.6f*%.0f', app.PesosFinales(i), entradas(i));
        end
    end
    fprintf(' + %.6f = %.6f\n', app.BiasFinal, salida);
    
    % Determinar clasificación binaria (umbral en 0)
    if salida >= 0
        clasificacion = 1;
    else
        clasificacion = 0;
    end
    fprintf('Clasificación binaria (umbral=0): %d\n', clasificacion);
        
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            % Reiniciar pesos y umbral
            app.W1EditField.Value = [];
            app.W2EditField.Value = [];
            app.W3EditField.Value = [];
            app.W4EditField.Value = [];
            app.UmbralEditField.Value = [];
            app.error_deseado.Value=[];


            % Reiniciar resultado
            app.RESULTADOEditField.Value = [];
        
            % Reiniciar variables de la app
            app.Entradas = [];
            app.SalidasDeseadas = [];
            app.Pesos = [];
            app.Umbral = [];
        
            % Reiniciar info de entrenamiento
            app.NumeroEpocasEditField.Value = 0;
            app.ErrorFinalEditField.Value = [];
            app.PrecisionEditField.Value = [];
        
            % Limpiar gráfica
            cla(app.error, 'reset');
            title(app.error, 'Entrenamiento');
            xlabel(app.error, 'Número de épocas');
            ylabel(app.error, 'Error');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.error = uiaxes(app.UIFigure);
            title(app.error, 'Title')
            xlabel(app.error, 'X')
            ylabel(app.error, 'Y')
            zlabel(app.error, 'Z')
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Imagen_de_WhatsApp_2025-09-26_a_las_09.52.28_815458df-removebg-preview.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo UNIVERSIDAD.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'nom_adaline.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'integrantes.png');
            app.CargarentradasysalidasesperadasButton = uibutton(app.UIFigure, 'push');
            app.CargarentradasysalidasesperadasButton.ButtonPushedFcn = createCallbackFcn(app, @CargarentradasysalidasesperadasButtonPushed, true);
            app.CargarentradasysalidasesperadasButton.Text = 'Cargar entradas y salidas esperadas';
            app.ENTRENARButton = uibutton(app.UIFigure, 'push');
            app.ENTRENARButton.ButtonPushedFcn = createCallbackFcn(app, @ENTRENARButtonPushed, true);
            app.ENTRENARButton.Text = 'ENTRENAR';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.error_deseado = uieditfield(app.UIFigure, 'numeric');
            app.error_deseado.AllowEmpty = 'on';
            app.error_deseado.Value = 10;
            app.CargarpesosButton = uibutton(app.UIFigure, 'push');
            app.CargarpesosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarpesosButtonPushed, true);
            app.CargarpesosButton.Text = 'Cargar pesos';
            app.GenerarpesosaleatoriosButton = uibutton(app.UIFigure, 'push');
            app.GenerarpesosaleatoriosButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarpesosaleatoriosButtonPushed, true);
            app.GenerarpesosaleatoriosButton.Text = 'Generar pesos aleatorios';
            app.W2EditFieldLabel = uilabel(app.UIFigure);
            app.W2EditFieldLabel.Text = 'W2';
            app.W2EditField = uieditfield(app.UIFigure, 'numeric');
            app.W2EditField.AllowEmpty = 'on';
            app.UmbralEditFieldLabel = uilabel(app.UIFigure);
            app.UmbralEditFieldLabel.Text = 'Umbral';
            app.UmbralEditField = uieditfield(app.UIFigure, 'numeric');
            app.UmbralEditField.AllowEmpty = 'on';
            app.W1EditFieldLabel = uilabel(app.UIFigure);
            app.W1EditFieldLabel.Text = 'W1';
            app.W1EditField = uieditfield(app.UIFigure, 'numeric');
            app.W1EditField.AllowEmpty = 'on';
            app.W3EditFieldLabel = uilabel(app.UIFigure);
            app.W3EditFieldLabel.Text = 'W3';
            app.W3EditField = uieditfield(app.UIFigure, 'numeric');
            app.W3EditField.AllowEmpty = 'on';
            app.ReiniciarButton = uibutton(app.UIFigure, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.NumerodeepocasrealizadasEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodeepocasrealizadasEditFieldLabel.Text = 'Numero de epocas realizadas';
            app.NumeroEpocasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumeroEpocasEditField.AllowEmpty = 'on';
            app.TasadeaprendizajeLabel = uilabel(app.UIFigure);
            app.TasadeaprendizajeLabel.Text = 'Tasa de aprendizaje 𝛼 =';
            app.TasadeaprendizajeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TasadeaprendizajeEditField.AllowEmpty = 'on';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.AllowEmpty = 'on';
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.AllowEmpty = 'on';
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Text = 'X3';
            app.X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditField.AllowEmpty = 'on';
            app.SELECCIONAUNCASODropDownLabel = uilabel(app.UIFigure);
            app.SELECCIONAUNCASODropDownLabel.Text = 'SELECCIONA UN CASO';
            app.SELECCIONAUNCASODropDown = uidropdown(app.UIFigure);
            app.SELECCIONAUNCASODropDown.Items = {'SELECCIONAR', 'Caso 1', 'Caso 2', 'Caso 3'};
            app.SELECCIONAUNCASODropDown.ValueChangedFcn = createCallbackFcn(app, @SELECCIONAUNCASODropDownValueChanged, true);
            app.SELECCIONAUNCASODropDown.Value = 'Caso 1';
            app.W4EditFieldLabel = uilabel(app.UIFigure);
            app.W4EditFieldLabel.Text = 'W4';
            app.W4EditField = uieditfield(app.UIFigure, 'numeric');
            app.W4EditField.AllowEmpty = 'on';
            app.X4EditFieldLabel = uilabel(app.UIFigure);
            app.X4EditFieldLabel.Text = 'X4';
            app.X4EditField = uieditfield(app.UIFigure, 'numeric');
            app.X4EditField.AllowEmpty = 'on';
            app.entrada1SwitchLabel = uilabel(app.UIFigure);
            app.entrada1SwitchLabel.Text = 'entrada1';
            app.entrada1Switch = uiswitch(app.UIFigure, 'slider');
            app.entrada1Switch.Items = {'0', '1'};
            app.entrada1Switch.Value = '0';
            app.entrada2SwitchLabel = uilabel(app.UIFigure);
            app.entrada2SwitchLabel.Text = 'entrada2';
            app.entrada2Switch = uiswitch(app.UIFigure, 'slider');
            app.entrada2Switch.Items = {'0', '1'};
            app.entrada2Switch.Value = '0';
            app.entrada3SwitchLabel = uilabel(app.UIFigure);
            app.entrada3SwitchLabel.Text = 'entrada3';
            app.entrada3Switch = uiswitch(app.UIFigure, 'slider');
            app.entrada3Switch.Items = {'0', '1'};
            app.entrada3Switch.Value = '0';
            app.entrada4SwitchLabel = uilabel(app.UIFigure);
            app.entrada4SwitchLabel.Text = 'entrada4';
            app.entrada4Switch = uiswitch(app.UIFigure, 'slider');
            app.entrada4Switch.Items = {'0', '1'};
            app.entrada4Switch.Value = '0';
            app.ComprobarButton = uibutton(app.UIFigure, 'push');
            app.ComprobarButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed, true);
            app.ComprobarButton.Text = 'Comprobar';
            app.RESULTADOEditFieldLabel = uilabel(app.UIFigure);
            app.RESULTADOEditFieldLabel.Text = 'RESULTADO';
            app.RESULTADOEditField = uieditfield(app.UIFigure, 'numeric');
            app.RESULTADOEditField.AllowEmpty = 'on';
            app.ErrorFinalEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorFinalEditFieldLabel.Text = 'Error Final';
            app.ErrorFinalEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorFinalEditField.AllowEmpty = 'on';
            app.PrecisionEditFieldLabel = uilabel(app.UIFigure);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecisionEditField.AllowEmpty = 'on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Adaline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end